classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioAnalyzerUIFigure   matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        GrafTab                 matlab.ui.container.Tab
        AnalysisAxes            matlab.ui.control.UIAxes
        InfoTab                 matlab.ui.container.Tab
        StatsLeftTextArea       matlab.ui.control.TextArea
        AudioFileInfoLabel_3    matlab.ui.control.Label
        StatsRightTextArea      matlab.ui.control.TextArea
        AudioFileInfoLabel_2    matlab.ui.control.Label
        InfoTextArea            matlab.ui.control.TextArea
        AudioFileInfoLabel      matlab.ui.control.Label
        SegmentTextArea         matlab.ui.control.TextArea
        InformcieLabel          matlab.ui.control.Label
        PoetsegmentovSlider     matlab.ui.control.Slider
        SegmentLabel            matlab.ui.control.Label
        LeftChannelCheckBox     matlab.ui.control.CheckBox
        RightChannelCheckBox    matlab.ui.control.CheckBox
        StopButton              matlab.ui.control.Button
        PauseButton             matlab.ui.control.Button
        PlayButton              matlab.ui.control.Button
        StartAnalysisButton     matlab.ui.control.Button
        AnalysisDropdown        matlab.ui.control.DropDown
        TypanalzyDropDownLabel  matlab.ui.control.Label
        LoadButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        audioFileObj AudioFile % Objekt triedy AudioFile
        selectedAnalysisType = "Waveform" % Typ vybranej analýzy
        AudioPlayerObj % Objekt pre prehrávanie zvuku
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file, path] = uigetfile({'*.wav;*.mp3', 'Audio Files (*.wav, *.mp3)'}, 'Vyberte audio súbor');
           
            if isequal(file, 0)
                disp('Načítanie súboru zrušené.');
                return;
            else
                    fullPath = fullfile(path, file);
                
                    audioFile = AudioFile.loadAudioFile(fullPath);
                    app.audioFileObj = audioFile;
                    app.LeftChannelCheckBox.Visible = true;
                    app.RightChannelCheckBox.Visible = true;
            
                    app.StartAnalysisButton.Enable = true;
                    app.PlayButton.Enable = true;
                    app.AnalysisDropdown.Enable = true;
                    startAnalysisButton(app);
                    PoetsegmentovSliderValueChanged(app);

                    app.InfoTextArea.Value = {['Cesta k súboru: ' fullPath];
                                              ['Vzorkovacia frekvencia: ' num2str(audioFile.SampleRate) ' Hz'];
                                              ['Dĺžka (sekundy): ' num2str(audioFile.Duration)]};            
            end
        end

        % Value changed function: AnalysisDropdown
        function analysisDropdownValueChanged(app, event)
            selectedAnalysis = app.AnalysisDropdown.Value;
            
            switch selectedAnalysis
                case 'Waveform'
                    app.selectedAnalysisType = 'Waveform';
                    app.LeftChannelCheckBox.Visible = true;
                    app.RightChannelCheckBox.Visible = true;
                case 'Frequency'
                    app.selectedAnalysisType = 'Frequency';
                    app.LeftChannelCheckBox.Visible = false;
                    app.RightChannelCheckBox.Visible = false;
            end

            startAnalysisButton(app);
            
        end

        % Button pushed function: StartAnalysisButton
        function startAnalysisButton(app, event)
            
            if isempty(app.audioFileObj)
                uialert(app.AudioAnalyzerUIFigure, 'Nebol načítaný žiadny audio súbor.', 'Chyba');
                return;
            end
            
            selectedType = app.selectedAnalysisType;
        
            switch selectedType
                case 'Waveform'                   
                    waveformAnalyzer = WaveformAnalysis(app.audioFileObj.Data);
                    waveformData = waveformAnalyzer.analyze(); 

                    stats = waveformAnalyzer.computeStatistics();

                    app.StatsLeftTextArea.Value = {['Max Hodnota: ' num2str(stats.maxValueLeft)], ...
                                                          ['Min Hodnota: ' num2str(stats.minValueLeft)], ...
                                                          ['Priemerná Hodnota: ' num2str(stats.meanValueLeft)], ...
                                                          ['Odchylka: ' num2str(stats.varianceLeft)]};
                                                          
                    app.StatsRightTextArea.Value = {['Max Hodnota: ' num2str(stats.maxValueRight)], ...
                                                           ['Min Hodnota: ' num2str(stats.minValueRight)], ...
                                                           ['Priemerná Hodnota: ' num2str(stats.meanValueRight)], ...
                                                           ['Odchylka: ' num2str(stats.varianceRight)]};


                    cla(app.AnalysisAxes);
                    AudioVisualizer.plotWaveform(app.AnalysisAxes, waveformData, app.audioFileObj.SampleRate);
                    app.AnalysisAxes.Visible = true;
        
                case 'Frequency'
                    frequencyAnalyzer = FrequencyAnalysis(app.audioFileObj.Data, app.audioFileObj.SampleRate);
                    frequencyResult = frequencyAnalyzer.analyze();
            
                    cla(app.AnalysisAxes);
                    AudioVisualizer.plotFrequency(app.AnalysisAxes, frequencyResult.frequency, frequencyResult.magnitude);            
                    app.AnalysisAxes.Visible = true;
        
                otherwise
                    uialert(app.AudioAnalyzerUIFigure, 'Nebol vybraný typ analýzy.', 'Chyba');
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if ~isempty(app.audioFileObj)
                app.AudioPlayerObj = audioplayer(app.audioFileObj.Data, app.audioFileObj.SampleRate);
                play(app.AudioPlayerObj);
                app.PlayButton.Enable = false;
                app.PauseButton.Enable = true;
                app.StopButton.Enable = true;
            else
                uialert(app.AudioAnalyzerUIFigure, 'Nie sú žiadne audio dáta na prehrávanie', 'Chyba');
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if ~isempty(app.AudioPlayerObj)
                if strcmp(app.AudioPlayerObj.Running, 'on')
                    pause(app.AudioPlayerObj);
                    app.PauseButton.Enable = false;
                    app.PlayButton.Enable = true;
                    app.StopButton.Enable = true;
                elseif strcmp(app.AudioPlayerObj.Running, 'off')
                    resume(app.AudioPlayerObj);
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if ~isempty(app.AudioPlayerObj)
                stop(app.AudioPlayerObj);
                app.StopButton.Enable = false;
                app.PlayButton.Enable = true;
                app.PauseButton.Enable = false;
            end
        end

        % Value changed function: LeftChannelCheckBox, RightChannelCheckBox
        function ChannelSelectionChanged(app, event)
            AudioVisualizer.updateWaveformPlot(app.AnalysisAxes, app.audioFileObj.Data, app.audioFileObj.SampleRate, app.LeftChannelCheckBox.Value, app.RightChannelCheckBox.Value);
        end

        % Value changed function: PoetsegmentovSlider
        function PoetsegmentovSliderValueChanged(app, event)
            segmentLength = floor(length(app.audioFileObj.Data) / floor(app.PoetsegmentovSlider.Value));
            waveformAnalyzer = WaveformAnalysis(app.audioFileObj.Data);
            segmentStats = waveformAnalyzer.analyzeBySegments(segmentLength);
                
                statsText = {};
                for i = 1:length(segmentStats)
                    stats = segmentStats{i};
                    statsText{end+1} = ['Segment ' num2str(i) ':'];
                    statsText{end+1} = [' Max Hodnota: ' num2str(stats.maxValue)];
                    statsText{end+1} = [' Min Hodnota: ' num2str(stats.minValue)];
                    statsText{end+1} = [' Priemerná Hodnota: ' num2str(stats.meanValue)];
                    statsText{end+1} = [' Odchylka: ' num2str(stats.variance)];
                    statsText{end+1} = ' ';
                end
                
                app.SegmentTextArea.Value = statsText;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AudioAnalyzerUIFigure = uifigure('Visible', 'off');
            app.AudioAnalyzerUIFigure.Name = 'Audio Analyzer';
            app.AudioAnalyzerUIFigure.Icon = fullfile(pathToMLAPP, 'Icons', 'FRI_H_S_eng_cb.png');
            app.LoadButton = uibutton(app.AudioAnalyzerUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Načítať súbor';
            app.TypanalzyDropDownLabel = uilabel(app.AudioAnalyzerUIFigure);
            app.TypanalzyDropDownLabel.Text = 'Typ analýzy';
            app.AnalysisDropdown = uidropdown(app.AudioAnalyzerUIFigure);
            app.AnalysisDropdown.Items = {'Vlnová forma', 'Frekvenčná analýza'};
            app.AnalysisDropdown.ItemsData = {'Waveform', 'Frequency'};
            app.AnalysisDropdown.ValueChangedFcn = createCallbackFcn(app, @analysisDropdownValueChanged, true);
            app.AnalysisDropdown.Enable = 'off';
            app.AnalysisDropdown.Value = 'Waveform';
            app.StartAnalysisButton = uibutton(app.AudioAnalyzerUIFigure, 'push');
            app.StartAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @startAnalysisButton, true);
            app.StartAnalysisButton.Enable = 'off';
            app.StartAnalysisButton.Visible = 'off';
            app.StartAnalysisButton.Text = 'Načítať analýzu';
            app.PlayButton = uibutton(app.AudioAnalyzerUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'Icons', 'play.png');
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = '';
            app.PauseButton = uibutton(app.AudioAnalyzerUIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = fullfile(pathToMLAPP, 'Icons', 'pause.png');
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = '';
            app.StopButton = uibutton(app.AudioAnalyzerUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = fullfile(pathToMLAPP, 'Icons', 'stop.png');
            app.StopButton.Enable = 'off';
            app.StopButton.Text = '';
            app.RightChannelCheckBox = uicheckbox(app.AudioAnalyzerUIFigure);
            app.RightChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionChanged, true);
            app.RightChannelCheckBox.Visible = 'off';
            app.RightChannelCheckBox.Text = 'Pravý kanál';
            app.RightChannelCheckBox.Value = true;
            app.LeftChannelCheckBox = uicheckbox(app.AudioAnalyzerUIFigure);
            app.LeftChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionChanged, true);
            app.LeftChannelCheckBox.Visible = 'off';
            app.LeftChannelCheckBox.Text = 'Ľavý kanál';
            app.LeftChannelCheckBox.Value = true;
            app.TabGroup = uitabgroup(app.AudioAnalyzerUIFigure);
            app.GrafTab = uitab(app.TabGroup);
            app.GrafTab.Title = 'Graf';
            app.AnalysisAxes = uiaxes(app.GrafTab);
            title(app.AnalysisAxes, 'Title')
            xlabel(app.AnalysisAxes, 'X')
            ylabel(app.AnalysisAxes, 'Y')
            zlabel(app.AnalysisAxes, 'Z')
            app.AnalysisAxes.Visible = 'off';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.SegmentLabel = uilabel(app.InfoTab);
            app.SegmentLabel.Text = 'Počet segmentov';
            app.PoetsegmentovSlider = uislider(app.InfoTab);
            app.PoetsegmentovSlider.Limits = [1 10];
            app.PoetsegmentovSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.PoetsegmentovSlider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.PoetsegmentovSlider.ValueChangedFcn = createCallbackFcn(app, @PoetsegmentovSliderValueChanged, true);
            app.PoetsegmentovSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.PoetsegmentovSlider.Value = 1;
            app.InformcieLabel = uilabel(app.InfoTab);
            app.InformcieLabel.Text = 'Informácie';
            app.SegmentTextArea = uitextarea(app.InfoTab);
            app.AudioFileInfoLabel = uilabel(app.InfoTab);
            app.AudioFileInfoLabel.Text = 'Zvukový súbor';
            app.InfoTextArea = uitextarea(app.InfoTab);
            app.InfoTextArea.Editable = 'off';
            app.AudioFileInfoLabel_2 = uilabel(app.InfoTab);
            app.AudioFileInfoLabel_2.Text = 'Pravý kanál';
            app.StatsRightTextArea = uitextarea(app.InfoTab);
            app.StatsRightTextArea.Editable = 'off';
            app.AudioFileInfoLabel_3 = uilabel(app.InfoTab);
            app.AudioFileInfoLabel_3.Text = 'Ľavý kanál';
            app.StatsLeftTextArea = uitextarea(app.InfoTab);
            app.StatsLeftTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.AudioAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudioAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioAnalyzerUIFigure)
        end
    end
end